classdef ProjeMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GetInformationButton  matlab.ui.control.Button
        WindSpeedLabel        matlab.ui.control.Label
        HumidtyLabel          matlab.ui.control.Label
        TemperatureLabel      matlab.ui.control.Label
        WeatherAppLabel       matlab.ui.control.Label
        Image6                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        sehirSecDropDown      matlab.ui.control.DropDown
        ehirSeinizLabel       matlab.ui.control.Label
        sehirEkleEditField    matlab.ui.control.EditField
        ehirEkleLabel         matlab.ui.control.Label
        sehirEkleButton       matlab.ui.control.Button
        windSpeedAxes         matlab.ui.control.UIAxes
        humidtyAxes           matlab.ui.control.UIAxes
        temperatureAxes       matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function weatherData = fetchFiveDayForecast(app, cityName, apiKey)
            %Api url'sini oluştur
            url = ['https://api.openweathermap.org/data/2.5/forecast?q=' cityName ,'&units=metric&appid=' apiKey];
            request = webread(url);%api isteğini yap
            data = request.list; % veri listesini al

            date = NaT(numel(data,1),1); %Tarih verilerini al
            temperature = zeros(size(data,1),1); %Sıcaklık verileri için boş matrix
            humidity = zeros(size(data,1),1); %Nem verileri için boş matrix
            windSpeed = zeros(size(data,1),1); %Rüzgar Hızı verileri için boş matrix
            n = length(data);%Veri uzunluğunu n adlı değişkene atadım.
            for i = 1:n %Api verilerini döngü yardımıyla parse ettim.
                if iscell(data)
                    date(i) = datetime(data{i}.dt, 'ConvertFrom', 'posixtime');
                    temperature(i) = data{i}.main.temp; %Sıcaklık verilerini aldım.
                    humidity(i) = data{i}.main.humidity;%Nem verilerini aldım.
                    windSpeed(i) = data{i}.wind.speed;% Rüzgar Hızı verilerini aldım.
                elseif isstruct(data)
                    date(i) = datetime(data(i).dt, 'ConvertFrom', 'posixtime');
                    temperature(i) = data(i).main.temp;
                    humidity(i) = data(i).main.humidity;
                    windSpeed(i) = data(i).wind.speed;
                end
            end
            %İşlenen verileri değişkenlere atadım.
            weatherData.Date=date;
            weatherData.Temperature = temperature;
            weatherData.Humidity = humidity;
            weatherData.WindSpeed = windSpeed;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: sehirEkleButton
        function sehirEkleButtonPushed(app, event)
            %Edit Field içindeki değerin boş olmasını kontrol ettim.
            if app.sehirEkleEditField.Value == ""
                error= msgbox("Lütfen Bir Şehir Ekleyiniz"); % Eğer boşsa pop-up ile uyarı gösterdim.
            else
                eklenecekSehir=app.sehirEkleEditField.Value; % Edit Field içindeki değeri eklenecekSehir adında değişkende tuttum.
                app.sehirSecDropDown.Items{end+1}=eklenecekSehir; %DropDown'ın son elemanına eklenecek şehri atadım.
                app.sehirEkleEditField.Value=""; % DropDown'a ekledikten sonra Edit Field içini temizledim.
            end
            
        end

        % Button pushed function: GetInformationButton
        function GetInformationButtonPushed(app, event)
            cityName = app.sehirSecDropDown.Value; %Verilerini göstermek istediğim şehri DropDown'dan aldım.
            apiKey = 'YOUR API KEY'; %OpenWeatherMap API
            weatherData = app.fetchFiveDayForecast(cityName, apiKey); %Hava Durumu verileri aldım.
            %Sıcaklık için grafik çizdim.
            plot(app.temperatureAxes, weatherData.Date,weatherData.Temperature,'-o');
            title(app.temperatureAxes, sprintf('Temperature Forecast for %s', cityName));
            xlabel(app.temperatureAxes,"Date");
            ylabel(app.temperatureAxes, 'Temperature (°C)');

            %Nem için grafik çizdim.
            plot(app.humidtyAxes,weatherData.Date,weatherData.Humidity,'-+');
            title(app.humidtyAxes, sprintf('Humidity Forecast for %s', cityName));
            xlabel(app.humidtyAxes,"Date");
            ylabel(app.humidtyAxes, 'Humidity (%)');

            %Rüzgar Hızı için grafik çizdim.
            plot(app.windSpeedAxes, weatherData.Date,weatherData.WindSpeed,'-*');
            title(app.windSpeedAxes, sprintf('Wind Speed Forecast for %s', cityName));
            xlabel(app.windSpeedAxes,"Date");
            ylabel(app.windSpeedAxes, 'Wind Speed (m/s)');


            %Havadurumu verilerini güncelledim.
            currentWeather.Temperature = weatherData.Temperature(1);%Api'den dönen hava durumu verilerinin en günceli
            currentWeather.Humidity = weatherData.Humidity(1);
            currentWeather.WindSpeed = weatherData.WindSpeed(1);

            %Etiketleri güncelledim
            app.TemperatureLabel.Text = sprintf('Temperature: %.1f °C', currentWeather.Temperature);
            app.HumidtyLabel.Text = sprintf('Humidity: %.0f%%', currentWeather.Humidity);
            app.WindSpeedLabel.Text = sprintf('Wind Speed: %.2f m/s', currentWeather.WindSpeed);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.temperatureAxes = uiaxes(app.UIFigure);
            title(app.temperatureAxes, 'Title')
            xlabel(app.temperatureAxes, 'X')
            ylabel(app.temperatureAxes, 'Y')
            zlabel(app.temperatureAxes, 'Z')
            app.humidtyAxes = uiaxes(app.UIFigure);
            title(app.humidtyAxes, 'Title')
            xlabel(app.humidtyAxes, 'X')
            ylabel(app.humidtyAxes, 'Y')
            zlabel(app.humidtyAxes, 'Z')
            app.windSpeedAxes = uiaxes(app.UIFigure);
            title(app.windSpeedAxes, 'Title')
            xlabel(app.windSpeedAxes, 'X')
            ylabel(app.windSpeedAxes, 'Y')
            zlabel(app.windSpeedAxes, 'Z')
            app.sehirEkleButton = uibutton(app.UIFigure, 'push');
            app.sehirEkleButton.ButtonPushedFcn = createCallbackFcn(app, @sehirEkleButtonPushed, true);
            app.sehirEkleButton.Text = 'Ekle';
            app.ehirEkleLabel = uilabel(app.UIFigure);
            app.ehirEkleLabel.Text = 'Şehir Ekle';
            app.sehirEkleEditField = uieditfield(app.UIFigure, 'text');
            app.ehirSeinizLabel = uilabel(app.UIFigure);
            app.ehirSeinizLabel.Text = 'Şehir Seçiniz';
            app.sehirSecDropDown = uidropdown(app.UIFigure);
            app.sehirSecDropDown.Items = {'Adana', 'Ankara', 'İzmir', 'İstanbul', 'Bursa'};
            app.sehirSecDropDown.Value = 'Adana';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'Stormclouds.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', '52196025795_06f077377a_c.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'images.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', '2016-november-03-how-snowy.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'images (1).jpg');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'images', '770b805d5c99c7931366c2e84e88f251.png');
            app.WeatherAppLabel = uilabel(app.UIFigure);
            app.WeatherAppLabel.Text = 'Weather App';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature: ';
            app.HumidtyLabel = uilabel(app.UIFigure);
            app.HumidtyLabel.Text = 'Humidty: ';
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'Wind Speed:';
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInformationButtonPushed, true);
            app.GetInformationButton.Text = 'Get Information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjeMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end